classdef arq_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GenerujButton                  matlab.ui.control.Button
        DugosygnauEditFieldLabel       matlab.ui.control.Label
        DugosygnauEditField            matlab.ui.control.NumericEditField
        ParametrkodowaniaEditFieldLabel  matlab.ui.control.Label
        ParametrkodowaniaEditField     matlab.ui.control.NumericEditField
        Prawdopodobiestwo2EditFieldLabel  matlab.ui.control.Label
        Prawdopodobiestwo2EditField    matlab.ui.control.NumericEditField
        DANELabel                      matlab.ui.control.Label
        RodzajkanauDropDownLabel       matlab.ui.control.Label
        RodzajkanauDropDown            matlab.ui.control.DropDown
        Prawdopodobiestwo1EditFieldLabel  matlab.ui.control.Label
        Prawdopodobiestwo1EditField    matlab.ui.control.NumericEditField
        RodzajkodowaniaDropDownLabel   matlab.ui.control.Label
        RodzajkodowaniaDropDown        matlab.ui.control.DropDown
        MaksymalnailoprbprzesaniaEditFieldLabel  matlab.ui.control.Label
        MaksymalnailoprbprzesaniaEditField  matlab.ui.control.NumericEditField
        Prawdopodobiestwo3EditFieldLabel  matlab.ui.control.Label
        Prawdopodobiestwo3EditField    matlab.ui.control.NumericEditField
        Prawdopodobiestwo4EditFieldLabel  matlab.ui.control.Label
        Prawdopodobiestwo4EditField    matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        WYNIKILabel                    matlab.ui.control.Label
        CzastransmisjisEditFieldLabel  matlab.ui.control.Label
        CzastransmisjisEditField       matlab.ui.control.NumericEditField
        CakowitanadmiarowoEditFieldLabel  matlab.ui.control.Label
        CakowitanadmiarowoEditField    matlab.ui.control.NumericEditField
        BitErrorRateBEREditFieldLabel  matlab.ui.control.Label
        BitErrorRateBEREditField       matlab.ui.control.NumericEditField
        IloprbprzesaniaEditFieldLabel  matlab.ui.control.Label
        IloprbprzesaniaEditField       matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerujButton
        function GenerujButtonPushed(app, event)
            
            [ time_taken, bit_error_rate, total_redundancy, attempts_taken] = arq_main(app.DugosygnauEditField.Value, ...
                app.MaksymalnailoprbprzesaniaEditField.Value,...
                app.RodzajkodowaniaDropDown.Value,...
                app.ParametrkodowaniaEditField.Value, ...
                app.RodzajkanauDropDown.Value,...
                app.Prawdopodobiestwo1EditField.Value, ...
                app.Prawdopodobiestwo2EditField.Value, ...
                app.Prawdopodobiestwo3EditField.Value, ...
                app.Prawdopodobiestwo4EditField.Value);
            
            app.CzastransmisjisEditField.Value = time_taken;
            app.CakowitanadmiarowoEditField.Value = total_redundancy;
            app.BitErrorRateBEREditField.Value = bit_error_rate;
            app.IloprbprzesaniaEditField.Value = attempts_taken;
            
        end

        % Value changed function: Prawdopodobiestwo4EditField
        function Prawdopodobiestwo4EditFieldValueChanged(app, event)
            value = app.Prawdopodobiestwo4EditField.Value;
            
        end

        % Value changed function: Prawdopodobiestwo1EditField
        function Prawdopodobiestwo1EditFieldValueChanged(app, event)
            value = app.Prawdopodobiestwo1EditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {412, 412};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {408, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerujButton = uibutton(app.LeftPanel, 'push');
            app.GenerujButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujButtonPushed, true);
            app.GenerujButton.Text = 'Generuj';
            app.DugosygnauEditFieldLabel = uilabel(app.LeftPanel);
            app.DugosygnauEditFieldLabel.Text = 'Długość sygnału';
            app.DugosygnauEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DugosygnauEditField.Limits = [1 Inf];
            app.DugosygnauEditField.Value = 1;
            app.ParametrkodowaniaEditFieldLabel = uilabel(app.LeftPanel);
            app.ParametrkodowaniaEditFieldLabel.Text = 'Parametr kodowania';
            app.ParametrkodowaniaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParametrkodowaniaEditField.Limits = [1 Inf];
            app.ParametrkodowaniaEditField.Value = 1;
            app.Prawdopodobiestwo2EditFieldLabel = uilabel(app.LeftPanel);
            app.Prawdopodobiestwo2EditFieldLabel.Text = 'Prawdopodobieństwo 2';
            app.Prawdopodobiestwo2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Prawdopodobiestwo2EditField.Limits = [0 100000];
            app.DANELabel = uilabel(app.LeftPanel);
            app.DANELabel.Text = 'DANE';
            app.RodzajkanauDropDownLabel = uilabel(app.LeftPanel);
            app.RodzajkanauDropDownLabel.Text = 'Rodzaj kanału';
            app.RodzajkanauDropDown = uidropdown(app.LeftPanel);
            app.RodzajkanauDropDown.Items = {'Kanał KBS', 'Kanał Gilberta'};
            app.RodzajkanauDropDown.ItemsData = {'1', '2'};
            app.RodzajkanauDropDown.Value = '1';
            app.Prawdopodobiestwo1EditFieldLabel = uilabel(app.LeftPanel);
            app.Prawdopodobiestwo1EditFieldLabel.Text = 'Prawdopodobieństwo 1';
            app.Prawdopodobiestwo1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Prawdopodobiestwo1EditField.Limits = [0 100000];
            app.Prawdopodobiestwo1EditField.ValueChangedFcn = createCallbackFcn(app, @Prawdopodobiestwo1EditFieldValueChanged, true);
            app.RodzajkodowaniaDropDownLabel = uilabel(app.LeftPanel);
            app.RodzajkodowaniaDropDownLabel.Text = 'Rodzaj kodowania';
            app.RodzajkodowaniaDropDown = uidropdown(app.LeftPanel);
            app.RodzajkodowaniaDropDown.Items = {'Bit parzystości', 'Suma kontrolna', 'CRC32'};
            app.RodzajkodowaniaDropDown.ItemsData = {'1', '2', '3'};
            app.RodzajkodowaniaDropDown.Value = '1';
            app.MaksymalnailoprbprzesaniaEditFieldLabel = uilabel(app.LeftPanel);
            app.MaksymalnailoprbprzesaniaEditFieldLabel.Text = 'Maksymalna ilość prób przesłania';
            app.MaksymalnailoprbprzesaniaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaksymalnailoprbprzesaniaEditField.Limits = [1 Inf];
            app.MaksymalnailoprbprzesaniaEditField.Value = 100;
            app.Prawdopodobiestwo3EditFieldLabel = uilabel(app.LeftPanel);
            app.Prawdopodobiestwo3EditFieldLabel.Text = 'Prawdopodobieństwo 3';
            app.Prawdopodobiestwo3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Prawdopodobiestwo3EditField.Limits = [0 100000];
            app.Prawdopodobiestwo4EditFieldLabel = uilabel(app.LeftPanel);
            app.Prawdopodobiestwo4EditFieldLabel.Text = 'Prawdopodobieństwo 4';
            app.Prawdopodobiestwo4EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Prawdopodobiestwo4EditField.Limits = [0 100000];
            app.Prawdopodobiestwo4EditField.ValueChangedFcn = createCallbackFcn(app, @Prawdopodobiestwo4EditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.WYNIKILabel = uilabel(app.RightPanel);
            app.WYNIKILabel.Text = 'WYNIKI';
            app.CzastransmisjisEditFieldLabel = uilabel(app.RightPanel);
            app.CzastransmisjisEditFieldLabel.Text = 'Czas transmisji (s)';
            app.CzastransmisjisEditField = uieditfield(app.RightPanel, 'numeric');
            app.CzastransmisjisEditField.Editable = 'off';
            app.CakowitanadmiarowoEditFieldLabel = uilabel(app.RightPanel);
            app.CakowitanadmiarowoEditFieldLabel.Text = 'Całkowita nadmiarowość';
            app.CakowitanadmiarowoEditField = uieditfield(app.RightPanel, 'numeric');
            app.CakowitanadmiarowoEditField.Editable = 'off';
            app.BitErrorRateBEREditFieldLabel = uilabel(app.RightPanel);
            app.BitErrorRateBEREditFieldLabel.Text = 'Bit Error Rate (BER)';
            app.BitErrorRateBEREditField = uieditfield(app.RightPanel, 'numeric');
            app.BitErrorRateBEREditField.Editable = 'off';
            app.IloprbprzesaniaEditFieldLabel = uilabel(app.RightPanel);
            app.IloprbprzesaniaEditFieldLabel.Text = 'Ilość prób przesłania ';
            app.IloprbprzesaniaEditField = uieditfield(app.RightPanel, 'numeric');
            app.IloprbprzesaniaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arq_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end